classdef GUI_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UploadVideoButton               matlab.ui.control.Button
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        SaveProcessedVideoButton        matlab.ui.control.Button
        DisplayBoundaryLayerButton      matlab.ui.control.StateButton
        InstantaneousVelocityButton     matlab.ui.control.Button
        AverageVelocityButton           matlab.ui.control.Button
        MaxSpreadRadiusButton           matlab.ui.control.Button
        SaveAnalyticsButton             matlab.ui.control.Button
        SatelliteDropletSpeedButton     matlab.ui.control.Button
        SatelliteDropletCountButton     matlab.ui.control.Button
        InstantaneousVelocityUnits      matlab.ui.control.EditField
        AverageVelocityUnits            matlab.ui.control.EditField
        MaxSpreadUnits                  matlab.ui.control.EditField
        DropletSpeedUnits               matlab.ui.control.EditField
        DropletCountUnits               matlab.ui.control.EditField
        CurrentFrameEditFieldLabel      matlab.ui.control.Label
        CurrentFrameEditField           matlab.ui.control.EditField
        InstantVelocityTxt              matlab.ui.control.NumericEditField
        AverageVelocityTxt              matlab.ui.control.NumericEditField
        MaxRadiusTxt                    matlab.ui.control.NumericEditField
        DropletSpeedTxt                 matlab.ui.control.NumericEditField
        DropletCountTxt                 matlab.ui.control.NumericEditField
        PixelsmmEditFieldLabel          matlab.ui.control.Label
        PixelsmmEditField               matlab.ui.control.NumericEditField
        ContactAngleButton              matlab.ui.control.Button
        ContactAngleUnits               matlab.ui.control.EditField
        ContactAngleTxt                 matlab.ui.control.NumericEditField
        TotalSatelliteDropletsButton    matlab.ui.control.Button
        TotalSatelliteDropletsTxt       matlab.ui.control.NumericEditField
        MaxVelocityPrimaryDropletButton  matlab.ui.control.Button
        MaxVelocityPrimaryDropletTxt    matlab.ui.control.NumericEditField
        MaxVelocityPrimaryDropletUnits  matlab.ui.control.EditField
        TotalSatelliteDropletsUnits     matlab.ui.control.EditField
        OutlineColorDropDownLabel       matlab.ui.control.Label
        OutlineColorDropDown            matlab.ui.control.DropDown
        SaveCurrentFrameButton          matlab.ui.control.Button
        SpreadRadius                    matlab.ui.control.Button
        SpreadRadiusUnits               matlab.ui.control.EditField
        SpreadRadiusTxt                 matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        R % total frame count of original video
        B % matrix of processed border video frames with the floor removed
        Ly % matrix of original video frames with a yellow border
        Lr % matrix of original video frames with a red border
        Lg % matrix of original video frames with a green border
        Lb % matrix of original video frames with a blue border
        DialogApp % Dialog box app
        floorheight % pixel height of floor
        floorangle % angle of floor
        mspreadl % max spread radius left
        mspreadr % max spread radius right
        spreadl % spread radius left
        spreadr % spread radius right
        pxl_to_mm % pixel to mm ratio
        cangle % contact angle (both left and right) for the matrix
        cpoints % contact position for the contact angles printing function
        fvelocity %fall velocity of droplet x and y directions
        impactdata % includes [speed,frame#] for impact of primary droplet
        totalobjects % a count of the number of separate objects in frame
        ctrmsprd % center found from maxspread function
    end
    
    properties (Access = public)
        M % matrix of orignal video frames
    end
    
    methods (Access = public)
        function updatefloor(app, h, t)
            % Store inputs as properties
            app.floorheight = h;
            app.floorangle = t;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadVideoButton
        function UploadVideoButtonPushed(app, event)
            [vidname,vidpath] = uigetfile('*.avi'); % this asks the user for a file input
            if vidname == 0
                figure(app.UIFigure); % this ensures that the app does not go to the background when uigetfile is called
                return; % this is a simple catch to stop the program from displaying an error if the user presses cancel
            end
            figure(app.UIFigure); % this ensures that the app does not go to the background when uigetfile is called
            fullname = fullfile(vidpath,vidname); % this concatenates the path and file name of the input file
            [app.M,app.R] = video2frame(fullname); 
            imshow(app.M(:,:,:,1),'Parent',app.UIAxes); % this displays the input file in the original video axes
            totalframes = app.R; % this sets totalframes to the number of frames in the uploaded video
            app.FrameSlider.Limits = [1 totalframes]; % this sets the slider limits to 1 to the total frames of the video
            app.FrameSlider.MajorTicks = [1:30:totalframes totalframes]; % this sets the slider major ticks to 1 to the total frames 
            % of the video in increments of 20
            app.FrameSlider.MinorTicks = [1:5:totalframes]; % this sets the slider minor ticks to 1 to the total frames 
            % of the video in increments of 5
            app.DialogApp = floorselectdlgbox(app,app.M);
            waitfor(app.DialogApp);
            B_withfloor = borders(app.M); % this processes the video to get a B&W border video
            app.B = floorremove(B_withfloor,app.floorheight,app.floorangle); % this processes the border video to remove the floor
            rotatedsource = convertSource(app.M,app.floorangle); % this rotates the source image for use in maskOverlay function
            omask = outlineMask(app.B); % this creates an outline mask from the border matrix
            app.Ly = maskOverlay(rotatedsource,omask,5,[255,255,0]); % this creates a matrix of frames with the original video
            % with a yellow line around the border
            app.Lr = maskOverlay(rotatedsource,omask,5,[255,0,0]); % this creates a matrix of frames with the original video
            % with a red line around the border
            app.Lg = maskOverlay(rotatedsource,omask,5,[0,255,0]); % this creates a matrix of frames with the original video
            % with a green line around the border
            app.Lb = maskOverlay(rotatedsource,omask,5,[0,0,255]); % this creates a matrix of frames with the original video
            % with a blue line around the border
            imshow(app.Ly(:,:,:,1),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            [app.cangle, app.cpoints] = contactAngles(app.B,app.floorheight);
            % amask = drawContactAngles([])
            [app.fvelocity, app.impactdata, app.totalobjects] = fallVelocity(app.B,app.floorheight); %this optains the fall veloctiy of the droplet and jet in the x and y directions
            [app.mspreadl, app.mspreadr, app.spreadl,app.spreadr, app.ctrmsprd] = maxSpread(app.B,app.floorheight); % this processes the B&W border video to find the spread radius per frame and max spread
            app.MaxRadiusTxt.Value = (app.mspreadl + app.mspreadr)/2;
            app.TotalSatelliteDropletsTxt.Value = max(app.totalobjects); % this sets the value of the total satellite droplets for the video
            [~,I] = max(abs(app.fvelocity(4,1,:)),[],'omitnan'); % this finds the index of the absolute maximum velocity
            app.MaxVelocityPrimaryDropletTxt.Value = app.fvelocity(4,1,I); % this sets the value of the absolute maximum velocity of the primary droplet
        end

        % Button pushed function: SaveAnalyticsButton
        function SaveAnalyticsButtonPushed(app, event)
            % Create two columns of data
            A  = rand(10,1);
            C = rand(10,1);
            % Create a table with the data and variable names
            T = table(A, C, 'VariableNames', { 'A', 'C'} );
            % Write data to text file
            [txtname,txtpath] = uiputfile('*.csv'); % this asks the user for a file destination
            fullpath = fullfile(txtpath,txtname); % this concatenates the path and file name of the file destination
            writetable(T, fullpath);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            imshow(app.M(:,:,:,round(value)),'Parent',app.UIAxes); % this displays the selected frame in the original video axes
            color = app.OutlineColorDropDown.Value;
            if strcmp(color,'Red')
                imshow(app.Lr(:,:,:,round(value)),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            elseif strcmp(color,'Blue')
                imshow(app.Lb(:,:,:,round(value)),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            elseif strcmp(color,'Yellow')
                imshow(app.Ly(:,:,:,round(value)),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            elseif strcmp(color,'Green')
                imshow(app.Lg(:,:,:,round(value)),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            end
            app.CurrentFrameEditField.Value = string(round(value)); % this sets the display in the Current Frame text field
            % to the value selected on the slider
            if  isnan(mean(app.cangle(:,round(value))))
                app.ContactAngleTxt.Visible = 'off';
            else
                app.ContactAngleTxt.Visible = 'on';
                app.ContactAngleTxt.Value = mean(app.cangle(:,round(value)));
            end
            
            if isnan(app.fvelocity(4,1,round(value)))   %checking to see if the veloctiy at the 'value' frame is a real number
                app.InstantVelocityTxt.Visible = 'off';
            else 
                app.InstantVelocityTxt.Visible = 'on';
                app.InstantVelocityTxt.Value = app.fvelocity(4,1,round(value));
            end
            
            if isnan(app.fvelocity(4,2,round(value)))   %checking to see if the veloctiy at the 'value' frame is a real number
                app.DropletSpeedTxt.Visible = 'off';
            else 
                app.DropletSpeedTxt.Visible = 'on';
                app.DropletSpeedTxt.Value = app.fvelocity(4,2,round(value));
            end
            
            if isnan(mean(app.fvelocity(4,1,1:round(value)),"omitnan"))   %checking to see if the average velocity at the current frame is a real number
                app.AverageVelocityTxt.Visible = 'off';
            else 
                app.AverageVelocityTxt.Visible = 'on';
                app.AverageVelocityTxt.Value = mean(app.fvelocity(4,1,1:round(value)),"omitnan");
            end
            if isnan(mean(app.spreadl(round(value)),"omitnan"))   %checking to see if the spread radius at the current frame is a real number
                app.SpreadRadiusTxt.Visible = 'off';
            else 
                app.SpreadRadiusTxt.Visible = 'on';
                app.SpreadRadiusTxt.Value = mean([app.spreadl(round(value)) app.spreadr(round(value))],"omitnan");
            end
            
            app.DropletCountTxt.Value = app.totalobjects(round(value));
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            imshow(app.M(:,:,:,round(changingValue)),'Parent',app.UIAxes); % this displays the selected frame in the original video axes
            value = app.OutlineColorDropDown.Value;
            if strcmp(value,'Red')
                imshow(app.Lr(:,:,:,round(changingValue)),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            elseif strcmp(value,'Blue')
                imshow(app.Lb(:,:,:,round(changingValue)),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            elseif strcmp(value,'Yellow')
                imshow(app.Ly(:,:,:,round(changingValue)),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            elseif strcmp(value,'Green')
                imshow(app.Lg(:,:,:,round(changingValue)),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            end
            app.CurrentFrameEditField.Value = string(round(changingValue)); % this sets the display in the Current Frame text field
            % to the value selected on the slider
            if  isnan(mean(app.cangle(:,round(changingValue))))
                app.ContactAngleTxt.Visible = 'off';
            else
                app.ContactAngleTxt.Visible = 'on';
                app.ContactAngleTxt.Value = mean(app.cangle(:,round(changingValue)));
            end
            
            if isnan(app.fvelocity(4,1,round(changingValue))) %checking to see if the veloctiy at the 'changingValue' frame is a real number
                app.InstantVelocityTxt.Visible = 'off';
            else
                app.InstantVelocityTxt.Visible = 'on';
                app.InstantVelocityTxt.Value = app.fvelocity(4,1,round(changingValue));
            end
            
            if isnan(app.fvelocity(4,2,round(changingValue))) %checking to see if the veloctiy at the 'changingValue' frame is a real number
                app.DropletSpeedTxt.Visible = 'off';
            else
                app.DropletSpeedTxt.Visible = 'on';
                app.DropletSpeedTxt.Value = app.fvelocity(4,2,round(changingValue));
            end
               
            if isnan(mean(app.fvelocity(4,1,1:round(changingValue)),"omitnan"))   %checking to see if the average velocity at the current frame is a real number
                app.AverageVelocityTxt.Visible = 'off';
            else 
                app.AverageVelocityTxt.Visible = 'on';
                app.AverageVelocityTxt.Value = mean(app.fvelocity(4,1,1:round(changingValue)),"omitnan");
            end
            
            if isnan(mean(app.spreadl(round(changingValue)),"omitnan"))   %checking to see if the spread radius at the current frame is a real number
                app.SpreadRadiusTxt.Visible = 'off';
            else 
                app.SpreadRadiusTxt.Visible = 'on';
                app.SpreadRadiusTxt.Value = mean([app.spreadl(round(changingValue)) app.spreadr(round(changingValue))],"omitnan");
            end
            
            app.DropletCountTxt.Value = app.totalobjects(round(changingValue));
        end

        % Button pushed function: SaveProcessedVideoButton
        function SaveProcessedVideoButtonPushed(app, event)
            [filename,filepath]=uiputfile('.JPEG');
            [~,~,~,sizeapp] = size(app.L);
            i=1;
            while(i <= sizeapp)
                frame2file(app.L,filename,filepath,i);
                i=i+1;
            end
        end

        % Value changed function: PixelsmmEditField
        function PixelsmmEditFieldValueChanged(app, event)
            app.pxl_to_mm = app.PixelsmmEditField.Value;
        end

        % Value changed function: OutlineColorDropDown
        function OutlineColorDropDownValueChanged(app, event)
            currentframe = round(app.FrameSlider.Value);
            value = app.OutlineColorDropDown.Value;
            if strcmp(value,'Red')
                imshow(app.Lr(:,:,:,currentframe),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            elseif strcmp(value,'Blue')
                imshow(app.Lb(:,:,:,currentframe),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            elseif strcmp(value,'Yellow')
                imshow(app.Ly(:,:,:,currentframe),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            elseif strcmp(value,'Green')
                imshow(app.Lg(:,:,:,currentframe),'Parent',app.UIAxes2); % this displays the B&W border video in the processed video axes
            end
        end

        % Button pushed function: SaveCurrentFrameButton
        function SaveCurrentFrameButtonPushed(app, event)
            currentframe = round(app.FrameSlider.Value);
            [filename,filepath]=uiputfile('.JPEG');
            value = app.OutlineColorDropDown.Value;
            if strcmp(value,'Red')
                frame2file(app.Lr,filename,filepath,currentframe); % this saves the current frame of the processed video axes
            elseif strcmp(value,'Blue')
                frame2file(app.Lb,filename,filepath,currentframe); % this saves the current frame of the processed video axes
            elseif strcmp(value,'Yellow')
                frame2file(app.Ly,filename,filepath,currentframe); % this saves the current frame of the processed video axes
            elseif strcmp(value,'Green')
                frame2file(app.Lg,filename,filepath,currentframe); % this saves the current frame of the processed video axes
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadVideoButton = uibutton(app.UIFigure, 'push');
            app.UploadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton.Text = 'Upload Video';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [0 15];
            app.FrameSlider.MajorTicks = [5 10 15];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.SaveProcessedVideoButton = uibutton(app.UIFigure, 'push');
            app.SaveProcessedVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProcessedVideoButtonPushed, true);
            app.SaveProcessedVideoButton.Text = 'Save Processed Video ';
            app.DisplayBoundaryLayerButton = uibutton(app.UIFigure, 'state');
            app.DisplayBoundaryLayerButton.Text = {'Display Boundary'; 'Layer'};
            app.InstantaneousVelocityButton = uibutton(app.UIFigure, 'push');
            app.InstantaneousVelocityButton.Text = 'Instantaneous Velocity';
            app.AverageVelocityButton = uibutton(app.UIFigure, 'push');
            app.AverageVelocityButton.Text = 'Average Velocity';
            app.MaxSpreadRadiusButton = uibutton(app.UIFigure, 'push');
            app.MaxSpreadRadiusButton.Text = 'Max Spread Radius';
            app.SaveAnalyticsButton = uibutton(app.UIFigure, 'push');
            app.SaveAnalyticsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalyticsButtonPushed, true);
            app.SaveAnalyticsButton.Text = 'Save Analytics';
            app.SatelliteDropletSpeedButton = uibutton(app.UIFigure, 'push');
            app.SatelliteDropletSpeedButton.Text = 'Satellite Droplet Speed';
            app.SatelliteDropletCountButton = uibutton(app.UIFigure, 'push');
            app.SatelliteDropletCountButton.Text = 'Satellite Droplet Count';
            app.InstantaneousVelocityUnits = uieditfield(app.UIFigure, 'text');
            app.InstantaneousVelocityUnits.Editable = 'off';
            app.InstantaneousVelocityUnits.Value = 'mm/s';
            app.AverageVelocityUnits = uieditfield(app.UIFigure, 'text');
            app.AverageVelocityUnits.Editable = 'off';
            app.AverageVelocityUnits.Value = 'mm/s';
            app.MaxSpreadUnits = uieditfield(app.UIFigure, 'text');
            app.MaxSpreadUnits.Editable = 'off';
            app.MaxSpreadUnits.Value = 'mm';
            app.DropletSpeedUnits = uieditfield(app.UIFigure, 'text');
            app.DropletSpeedUnits.Editable = 'off';
            app.DropletSpeedUnits.Value = 'mm/s';
            app.DropletCountUnits = uieditfield(app.UIFigure, 'text');
            app.DropletCountUnits.Editable = 'off';
            app.DropletCountUnits.Value = 'droplets';
            app.CurrentFrameEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrameEditFieldLabel.Text = 'Current Frame';
            app.CurrentFrameEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentFrameEditField.Editable = 'off';
            app.CurrentFrameEditField.Value = '#';
            app.InstantVelocityTxt = uieditfield(app.UIFigure, 'numeric');
            app.AverageVelocityTxt = uieditfield(app.UIFigure, 'numeric');
            app.MaxRadiusTxt = uieditfield(app.UIFigure, 'numeric');
            app.DropletSpeedTxt = uieditfield(app.UIFigure, 'numeric');
            app.DropletCountTxt = uieditfield(app.UIFigure, 'numeric');
            app.PixelsmmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsmmEditFieldLabel.Text = 'Pixels/mm';
            app.PixelsmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsmmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsmmEditFieldValueChanged, true);
            app.ContactAngleButton = uibutton(app.UIFigure, 'push');
            app.ContactAngleButton.Text = 'Contact Angle';
            app.ContactAngleUnits = uieditfield(app.UIFigure, 'text');
            app.ContactAngleUnits.Editable = 'off';
            app.ContactAngleUnits.Value = 'degrees';
            app.ContactAngleTxt = uieditfield(app.UIFigure, 'numeric');
            app.TotalSatelliteDropletsButton = uibutton(app.UIFigure, 'push');
            app.TotalSatelliteDropletsButton.Text = 'Total Satellite Droplets';
            app.TotalSatelliteDropletsTxt = uieditfield(app.UIFigure, 'numeric');
            app.MaxVelocityPrimaryDropletButton = uibutton(app.UIFigure, 'push');
            app.MaxVelocityPrimaryDropletButton.Text = 'Max Velocity (Primary Droplet)';
            app.MaxVelocityPrimaryDropletTxt = uieditfield(app.UIFigure, 'numeric');
            app.MaxVelocityPrimaryDropletUnits = uieditfield(app.UIFigure, 'text');
            app.MaxVelocityPrimaryDropletUnits.Editable = 'off';
            app.MaxVelocityPrimaryDropletUnits.Value = 'mm/s';
            app.TotalSatelliteDropletsUnits = uieditfield(app.UIFigure, 'text');
            app.TotalSatelliteDropletsUnits.Editable = 'off';
            app.TotalSatelliteDropletsUnits.Value = 'droplets';
            app.OutlineColorDropDownLabel = uilabel(app.UIFigure);
            app.OutlineColorDropDownLabel.Text = 'Outline Color';
            app.OutlineColorDropDown = uidropdown(app.UIFigure);
            app.OutlineColorDropDown.Items = {'Yellow', 'Green', 'Blue', 'Red'};
            app.OutlineColorDropDown.ValueChangedFcn = createCallbackFcn(app, @OutlineColorDropDownValueChanged, true);
            app.OutlineColorDropDown.Value = 'Yellow';
            app.SaveCurrentFrameButton = uibutton(app.UIFigure, 'push');
            app.SaveCurrentFrameButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentFrameButtonPushed, true);
            app.SaveCurrentFrameButton.Text = 'Save Current Frame';
            app.SpreadRadius = uibutton(app.UIFigure, 'push');
            app.SpreadRadius.Text = 'Spread Radius';
            app.SpreadRadiusUnits = uieditfield(app.UIFigure, 'text');
            app.SpreadRadiusUnits.Editable = 'off';
            app.SpreadRadiusUnits.Value = 'mm';
            app.SpreadRadiusTxt = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Video')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Processed Video')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end